classdef APP_check_fames_after_preds < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        SelectframeSpinner       matlab.ui.control.Spinner
        SelectframeSpinnerLabel  matlab.ui.control.Label
        FreerunSwitch            matlab.ui.control.RockerSwitch
        FreerunSwitchLabel       matlab.ui.control.Label
        LoadPredsButton          matlab.ui.control.Button
        NotsavedyetLamp          matlab.ui.control.Lamp
        NotsavedyetLampLabel     matlab.ui.control.Label
        LoadVideoButton          matlab.ui.control.Button
        FrameNrSlider            matlab.ui.control.Slider
        FrameNrSliderLabel       matlab.ui.control.Label
        UIAxes                   matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
         file % cam name
         path % where saved
         vid % This is the fulllength video
         nr_frames % This is the total nr of frames of the video
         % These are the output variables
         viewed_frame
         positions
         repo_path
         camparams
         M 
         prediction_folder
         skeleton
         this_frame
         toplay = 'Off'
    end
    
    methods (Access = private)
        
        function plotimage(app)
            imagesc(app.UIAxes, undistortImage(app.this_frame, app.camparams));
            names = fieldnames(app.positions);
            preds.predictions = app.positions;
            names(ismember(names, 'sampleID')) = [];
            ind_to_plot = app.viewed_frame;
            pts_this_frame = NaN(numel(names),3);
            %     title(ax, str_title, 'Color','w')
            for jj = 1:numel(names)
                % don't plot markers that drop out
                % if ~isnan(sum(preds.predictions.(names{jj})(ind_to_plot,:),2))
                if (~sum(preds.predictions.(names{jj})(ind_to_plot,:),2) == 0)
                    xx = squeeze(preds.predictions.(names{jj})(ind_to_plot,1));
                    yy = squeeze(preds.predictions.(names{jj})(ind_to_plot,2));
                    zz = squeeze(preds.predictions.(names{jj})(ind_to_plot,3));
                    % handles_here{jj} = line(xx,yy,zz,'Marker','o','Color',skeleton.color(jj,:),'MarkerFaceColor',skeleton.color(jj,:),'MarkerSize',5);
                    pts_this_frame(jj,:) = [xx,yy,zz];


                    hold(app.UIAxes,"on")
                    marker_plot(jj) = 1;
                else
                    marker_plot(jj) = 0;
                end

                % end
            end

            pts = pts_this_frame;
            projPts = [pts, ones(size(pts, 1), 1)] * app.M;
            projPts(:, 1:2) = projPts(:, 1:2) ./ projPts(:, 3);
            scatter(projPts(:,1), projPts(:,2), 'ro', 'filled', 'Marker','o','Parent', app.UIAxes)

            %% plot the links between markers
            links = app.skeleton.joints_idx;
            colors = app.skeleton.color;
            n_links = length(links);
            for mm = 1:(n_links)
                %         if numel(mocapstruct.links{mm})
                %             if (ismember(mocapstruct.links{mm}(1),1:numel(mocapstruct.markernames)) && ismember(mocapstruct.links{mm}(2),1:numel(mocapstruct.markernames)))
                %                 if (marker_plot(mocapstruct.links{mm}(1)) == 1 && marker_plot(mocapstruct.links{mm}(2)) == 1)

                xx = [squeeze(preds.predictions.(names{links(mm,1)})(ind_to_plot,1)) ...
                    squeeze(preds.predictions.(names{links(mm,2)})(ind_to_plot,1)) ];
                yy = [squeeze(preds.predictions.(names{links(mm,1)})(ind_to_plot,2)) ...
                    squeeze(preds.predictions.(names{links(mm,2)})(ind_to_plot,2)) ];
                zz = [squeeze(preds.predictions.(names{links(mm,1)})(ind_to_plot,3)) ...
                    squeeze(preds.predictions.(names{links(mm,2)})(ind_to_plot,3)) ];

                % x
                pts = [xx(1), yy(1), zz(1)];

                projPts = [pts, ones(size(pts, 1), 1)] * app.M;
                projPts(:, 1:2) = projPts(:, 1:2) ./ projPts(:, 3);

                pts2  =[xx(2), yy(2), zz(2)];
                projPts2 = [pts2, ones(size(pts2, 1), 1)] * app.M;
                projPts2(:, 1:2) = projPts2(:, 1:2) ./ projPts2(:, 3);

                xx = [projPts(1), projPts2(1)];
                yy = [projPts(2), projPts2(2)];
                zz = [projPts(3), projPts2(3)];
                this_color = colors(mm, 1:3);
                line(xx,yy,'Color',this_color,'LineWidth',1, 'Parent',app.UIAxes);


            end

            axis off
            text(100,150,num2str(app.viewed_frame), 'color', 'w', 'Parent', app.UIAxes)
            drawnow
            hold off


        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: FrameNrSlider
        function FrameNrSliderValueChanged(app, event)
            num_frames = app.nr_frames;
            slide_vals = [1,num_frames];
            
            app.FrameNrSlider.Limits = slide_vals;
            app.viewed_frame = round(app.FrameNrSlider.Value);
            this_frame_temp = read(app.vid, app.viewed_frame);
            app.this_frame = this_frame_temp;
            % run plot
            plotimage(app)


%             title(app.UIAxes, app.file)

        end

        % Button pushed function: LoadVideoButton
        function loadVideo(app, event)
            app.NotsavedyetLamp.Color = [1 0 0];
            [app.file, app.path] = uigetfile('*.mp4');
            app.vid = VideoReader(fullfile(app.path,app.file));
            vid_temp = app.vid;
            n_frames = vid_temp.NumFrames;
            app.nr_frames = n_frames;
            FrameNrSliderValueChanged(app)
        end

        % Callback function: not associated with a component
        function save_table(app, event)
            T= array2table([app.ext_start, app.ext_end, app.in_start, app.in_end],...
                'VariableNames',{'Extrinsic Start', 'Extrinsic End', ...
                'Intrinsic Start', 'Intrinsic end'});
            cam_name = strsplit(app.file, '.');
            cam_name = cam_name{1};
            saveto = fullfile(app.path,[cam_name, '_cal_frames_to_trim.mat']);
            save(saveto, 'T')
            app.NotsavedyetLamp.Color = [0 1 0];
            app.NotsavedyetLampLabel.Text = ['Saved in ', app.path];

        end

        % Callback function: not associated with a component
        function get_frame(app, event)
            switch app.ChooseDropDown.Value
                case 'Intrinsic Start'
                    app.in_start = app.viewed_frame;
                case 'Intrinsic End'
                    app.in_end = app.viewed_frame;
                case 'Extrinsic Start'
                    app.ext_start = app.viewed_frame;
                case 'Extrinsic End'
                    app.ext_end = app.viewed_frame;
            end
        end

        % Button pushed function: LoadPredsButton
        function LoadPreds(app, event)
            app.repo_path = 'H:\Cam_cal_and_label3D_Nevian';
            addpath(app.repo_path)
            addpath(genpath(fullfile(app.repo_path ,'Label3D', 'deps')))
            addpath(genpath(fullfile(app.repo_path ,'Label3D' , 'skeletons')))
            rmpath(genpath(fullfile(app.repo_path ,'Label3D','deps','Animator')))


            % Load sklton
            sklton = 'mouse22.mat';
            app.skeleton = load(sklton);


            % load preds
            [predfile, app.prediction_folder] = uigetfile('predictions.mat');
            preds = load(fullfile(app.prediction_folder,predfile));
            app.positions = preds.predictions;


            % Load cam parameters
            this_camera = preds.cameras.(['Camera', num2str(1)]);
            K = this_camera.IntrinsicMatrix;
            R =  this_camera.rotationMatrix;
            t = this_camera.translationVector;
            app.M = [R; t] * K;
            % transpose K
            K1 = K';
            try
                app.camparams = cameraParameters(K = K1, RadialDistortion = this_camera.RadialDistortion,...
                    TangentialDistortion= this_camera.TangentialDistortion);
            catch
                app.camparams = cameraParameters(IntrinsicMatrix = K, RadialDistortion = this_camera.RadialDistortion,...
                    TangentialDistortion= this_camera.TangentialDistortion);
            end


        end

        % Value changed function: FreerunSwitch
        function switchplay(app, event)
            
            app.toplay = app.FreerunSwitch.Value;
            if strcmp(app.toplay,'On')
%                 while strcmp(app.toplay,'On')

                    for iframe = app.viewed_frame :app.nr_frames
                        if strcmp(app.toplay,'On')
                            app.viewed_frame = iframe;
                            this_frame_temp = read(app.vid, app.viewed_frame);
                            app.this_frame = this_frame_temp;
                            plotimage(app)
                        else
                            break
                        end

%                         keyboard

                    end
               
%                 end
            end
            
        end

        % Value changed function: SelectframeSpinner
        function setframe(app, event)
            value = app.SelectframeSpinner.Value;
            app.viewed_frame = value;    
            this_frame_temp = read(app.vid, app.viewed_frame);
            app.this_frame = this_frame_temp;
            % run plot
            plotimage(app)
        end

        % Value changing function: SelectframeSpinner
        function setchangingframes(app, event)
            changingValue = event.Value;
            app.viewed_frame = changingValue;
            this_frame_temp = read(app.vid, app.viewed_frame);
            app.this_frame = this_frame_temp;
            % run plot
            plotimage(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'camera behavior')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.FrameNrSliderLabel = uilabel(app.UIFigure);
            app.FrameNrSliderLabel.Text = 'FrameNr';
            app.FrameNrSlider = uislider(app.UIFigure);
            app.FrameNrSlider.ValueChangedFcn = createCallbackFcn(app, @FrameNrSliderValueChanged, true);
            app.FrameNrSlider.Value = 1;
            app.LoadVideoButton = uibutton(app.UIFigure, 'push');
            app.LoadVideoButton.ButtonPushedFcn = createCallbackFcn(app, @loadVideo, true);
            app.LoadVideoButton.Text = 'LoadVideo';
            app.NotsavedyetLampLabel = uilabel(app.UIFigure);
            app.NotsavedyetLampLabel.Text = 'Not saved yet';
            app.NotsavedyetLamp = uilamp(app.UIFigure);
            app.LoadPredsButton = uibutton(app.UIFigure, 'push');
            app.LoadPredsButton.ButtonPushedFcn = createCallbackFcn(app, @LoadPreds, true);
            app.LoadPredsButton.Text = 'LoadPreds';
            app.FreerunSwitchLabel = uilabel(app.UIFigure);
            app.FreerunSwitchLabel.Text = 'Free run';
            app.FreerunSwitch = uiswitch(app.UIFigure, 'rocker');
            app.FreerunSwitch.ValueChangedFcn = createCallbackFcn(app, @switchplay, true);
            app.SelectframeSpinnerLabel = uilabel(app.UIFigure);
            app.SelectframeSpinnerLabel.Text = 'Select frame';
            app.SelectframeSpinner = uispinner(app.UIFigure);
            app.SelectframeSpinner.ValueChangingFcn = createCallbackFcn(app, @setchangingframes, true);
            app.SelectframeSpinner.ValueDisplayFormat = '%.0f';
            app.SelectframeSpinner.ValueChangedFcn = createCallbackFcn(app, @setframe, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = APP_check_fames_after_preds

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end