classdef APP_trim_frames_for_calibr_and_get_ROI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        drawROISwitch         matlab.ui.control.Switch
        drawROISwitchLabel    matlab.ui.control.Label
        NotsavedyetLamp       matlab.ui.control.Lamp
        NotsavedyetLampLabel  matlab.ui.control.Label
        GetFrameButton        matlab.ui.control.Button
        DoneButton            matlab.ui.control.Button
        ChooseDropDown        matlab.ui.control.DropDown
        ChooseDropDownLabel   matlab.ui.control.Label
        LoadVideoButton       matlab.ui.control.Button
        FrameNrSlider         matlab.ui.control.Slider
        FrameNrSliderLabel    matlab.ui.control.Label
        UIAxes                matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
         file % cam name
         path % where saved
         vid % This is the fulllength video
         nr_frames % This is the total nr of frames of the video
         % These are the output variables
         viewed_frame
         in_start=0 %  Intirinsic start frame
         in_end =0
         ext_start
         ext_end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: FrameNrSlider
        function FrameNrSliderValueChanged(app, event)
            num_frames = app.nr_frames;
            slide_vals = [1,num_frames];
            app.FrameNrSlider.Limits = slide_vals;
            app.viewed_frame = round(app.FrameNrSlider.Value);
            this_frame = read(app.vid, app.viewed_frame);
            imagesc(app.UIAxes, this_frame);
            title(app.UIAxes, app.file)

        end

        % Button pushed function: LoadVideoButton
        function loadVideo(app, event)
            app.NotsavedyetLamp.Color = [1 0 0];
            [app.file, app.path] = uigetfile('*.mp4');
            app.vid = VideoReader(fullfile(app.path,app.file));
            vid_temp = app.vid;
            n_frames = vid_temp.NumFrames;
            app.nr_frames = n_frames;
            FrameNrSliderValueChanged(app)
        end

        % Button pushed function: DoneButton
        function save_table(app, event)
            T= array2table([app.ext_start, app.ext_end, app.in_start, app.in_end],...
                'VariableNames',{'Extrinsic Start', 'Extrinsic End', ...
                'Intrinsic Start', 'Intrinsic end'});
            cam_name = strsplit(app.file, '.');
            cam_name = cam_name{1};
            saveto = fullfile(app.path,[cam_name, '_cal_frames_to_trim.mat']);
            save(saveto, 'T')
            app.NotsavedyetLamp.Color = [0 1 0];
            app.NotsavedyetLampLabel.Text = ['Saved in ', app.path];

        end

        % Button pushed function: GetFrameButton
        function get_frame(app, event)
            switch app.ChooseDropDown.Value
                case 'Intrinsic Start'
                    app.in_start = app.viewed_frame;
                case 'Intrinsic End'
                    app.in_end = app.viewed_frame;
                case 'Extrinsic Start'
                    app.ext_start = app.viewed_frame;
                case 'Extrinsic End'
                    app.ext_end = app.viewed_frame;
            end
        end

        % Value changed function: drawROISwitch
        function toggle_draw_roi(app, event)
            value = app.drawROISwitch.Value;
            if strcmp(value, 'On')
                cam_name = strsplit(app.file, '.');
                cam_name = cam_name{1};
                h= drawcircle(app.UIAxes);
                % Wait for the user to double-click on the freehand
                wait(h);
                % Create a binary mask for the ROI
                binaryMask = h.createMask();
                saveto = fullfile(app.path,[cam_name, '_led_roi_binary_mask.mat']);
                save(saveto, 'binaryMask')
                disp('ROI mask saved')
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'camera behavior')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.FrameNrSliderLabel = uilabel(app.UIFigure);
            app.FrameNrSliderLabel.Text = 'FrameNr';
            app.FrameNrSlider = uislider(app.UIFigure);
            app.FrameNrSlider.ValueChangedFcn = createCallbackFcn(app, @FrameNrSliderValueChanged, true);
            app.FrameNrSlider.Value = 1;
            app.LoadVideoButton = uibutton(app.UIFigure, 'push');
            app.LoadVideoButton.ButtonPushedFcn = createCallbackFcn(app, @loadVideo, true);
            app.LoadVideoButton.Text = 'LoadVideo';
            app.ChooseDropDownLabel = uilabel(app.UIFigure);
            app.ChooseDropDownLabel.Text = 'Choose';
            app.ChooseDropDown = uidropdown(app.UIFigure);
            app.ChooseDropDown.Items = {'Intrinsic Start', 'Intrinsic End', 'Extrinsic Start', 'Extrinsic End'};
            app.ChooseDropDown.Value = 'Intrinsic Start';
            app.DoneButton = uibutton(app.UIFigure, 'push');
            app.DoneButton.ButtonPushedFcn = createCallbackFcn(app, @save_table, true);
            app.DoneButton.Text = 'Done';
            app.GetFrameButton = uibutton(app.UIFigure, 'push');
            app.GetFrameButton.ButtonPushedFcn = createCallbackFcn(app, @get_frame, true);
            app.GetFrameButton.Text = 'GetFrame';
            app.NotsavedyetLampLabel = uilabel(app.UIFigure);
            app.NotsavedyetLampLabel.Text = 'Not saved yet';
            app.NotsavedyetLamp = uilamp(app.UIFigure);
            app.drawROISwitchLabel = uilabel(app.UIFigure);
            app.drawROISwitchLabel.Text = 'draw ROI';
            app.drawROISwitch = uiswitch(app.UIFigure, 'slider');
            app.drawROISwitch.ValueChangedFcn = createCallbackFcn(app, @toggle_draw_roi, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = APP_trim_frames_for_calibr_and_get_ROI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end